classdef segmentationEvaluator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationEvaluatorUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        EraseButton                     matlab.ui.control.Button
        ConfirmLamp                     matlab.ui.control.Lamp
        ConfirmLampLabel                matlab.ui.control.Label
        DrawLamp                        matlab.ui.control.Lamp
        DrawLampLabel                   matlab.ui.control.Label
        ProblemDropDown                 matlab.ui.control.DropDown
        ChooseCaseLabel                 matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ConfirmButton                   matlab.ui.control.Button
        DrawButton                      matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        PixelAccuracyDisplay            matlab.ui.control.TextArea
        PixelAccuracyLabel              matlab.ui.control.Label
        HausdorffDistanceDisplay        matlab.ui.control.TextArea
        HausdorffDistanceTextAreaLabel  matlab.ui.control.Label
        LeaderboardButton               matlab.ui.control.Button
        DiceScoreLabel                  matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        DiceGauge                       matlab.ui.control.Gauge
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Default image and label
        defaultImage = "data/candidate01_brainstem_c3sag128.png"
        defaultLabel = "data/label01_c3sag128.png"

        % instruction above the image
        instructionTitle = "Label the brainstem - click on 'Draw' to start!";

        % Location to save images for leaderboard
        savelocation = "savedsegs";

        % Location for data
        datalocation = "data";

        % Store the current case number.
        currentCase = "Problem01";

        % Flag for draw or erase mode
        mode = "none";

        % display image - this is different from the read image due to
        % overlays and such.
        imageToDisplay

        % the image data to run segmentation on
        imageToSegment  
        
        % mask corresponding to the image
        segmentMask

        % human-drawn segment
        humanSegmentMask

        % Handle for new segmentations
        newFH
    end
    
    methods (Access = private)
        
        function updateGauge(app, value)
            app.DiceGauge.Value = value;
        end
        
        function askToSaveSegmentation(app)
            msg = 'Saving this segmentation adds it to the leaderboard.';
            title = 'Confirm Save';

            saveDialog = uifigure('Name', title, 'Visible', 'off');
            saveDialog.WindowStyle = 'modal';
            saveDialog.Position = [100 100 400 200];
            
            lbl = uilabel(saveDialog, ...
                'Position',[50 150 300 22]);
            lbl.Text = msg;

            name_lbl = uilabel(saveDialog, ...
                'Position',[50 100 150 22]);
            name_lbl.Text = "Enter Unique Name:";

            txt = uieditfield(saveDialog, ...
                'Position',[200 100 150 22]);

            yesBtn = uibutton(saveDialog, 'ButtonPushedFcn', @(btn,event) yesButtonPushed());
            yesBtnX = 50;
            yesBtnY = 50;
            yesBtnWidth = 100;
            yesBtnHeight = 22;
            yesBtn.Position = [yesBtnX yesBtnY yesBtnWidth yesBtnHeight];
            yesBtn.Text = 'Yes';

            noBtn = uibutton(saveDialog,  'ButtonPushedFcn', @(btn,event) noButtonPushed());
            noBtnX = 250;
            noBtnY = 50;
            noBtnWidth = 100;
            noBtnHeight = 22;
            noBtn.Position = [noBtnX noBtnY noBtnWidth noBtnHeight];
            noBtn.Text = 'No';
            saveDialog.Visible = 'on';

            function yesButtonPushed(~)
                app.saveCurrentSegmentation(txt.Value);
                close(saveDialog);
            end

            function noButtonPushed(~)
                close(saveDialog);
            end
        end
        
        function saveCurrentSegmentation(app, unique_name)
            if ~exist(app.savelocation, "dir")
                mkdir(app.savelocation)
            end
            fname = string(string(app.currentCase) + "_" + unique_name + "_" + posixtime(datetime('now'))) + ".png";
            imwrite(app.humanSegmentMask, fullfile(app.savelocation, fname));
        end
        
        function lb = constructLeaderboard(app)
            % already tested that savelocation exists
            files = dir(app.savelocation + filesep + "*.png");
            place = {};
            flist = {};
            dsc = {};
            hausdorff = {};
            pixelAccuracy = {};
            
            matchedIdx = 1;
            for idx = 1:numel(files)
                fname = files(idx).name;
                fparts = strsplit(fname, "_");
                if strcmp(fparts{1}, app.currentCase)
                    if numel(fparts) == 3
                        unique_name = fparts{2};
                    else
                        unique_name = "";
                        for idx = 2:numel(fparts) - 1
                            unique_name = unique_name + fparts{idx} + "_"; 
                        end
                    end
                    flist{matchedIdx} = unique_name;
                    dsc{matchedIdx} = app.computeMetric(fname, "dsc");
                    hausdorff{matchedIdx} = app.computeMetric(fname, "hausdorff");
                    pixelAccuracy{matchedIdx} = app.computeMetric(fname, "pixelaccuracy");
                    matchedIdx = matchedIdx + 1;
                end
            end
            if matchedIdx == 1
                flist{1} = "No entries yet; save a segmentation to be the first!";
                lb = table(flist', ...
                           'VariableNames', app.currentCase + " - Name");
            else
                lb = table(flist', dsc', hausdorff', pixelAccuracy', ...
                           'VariableNames',[app.currentCase + " - Name", "DSC", "Hausdorff", "PixelAccuracy"]);
                lb = sortrows(lb, "DSC", "descend");
                
                % Add a Rank column to see rank.
                lb.('Rank') = (1:height(lb))';
                
                % Reorder so that rank is first column
                lb = [lb(:, end), lb(:, 1:end-1)];
            end
        end
        
        function results = computeMetric(app, fname, metric)
            
            im1 = imread(fullfile(app.savelocation, fname));
            
            problemID = strsplit(fname, "_");
            problemID = problemID{1};
            problem_num = strrep(problemID, "Problem", "");
            
            label_path = fullfile(app.datalocation, "label");
            file_path = dir(label_path + num2str(problem_num, '%02d') + "*.png");
            file_name = fullfile(app.datalocation, file_path(1).name);
            im2 = imread(file_name);
            
            switch metric
                case "dsc"
                    results = app.computeDSC(im1, im2);
                case "hausdorff"
                    im1 = logical(im1);
                    im2 = logical(im2);
                    results = imhausdorff(im1, im2, 'euclidean');
                case "pixelaccuracy"
                    im1 = logical(im1);
                    im2 = logical(im2);
                    results = sum(im1(:) == im2(:)) * 100 /numel(im1);
            end
        end
        
        function diceCoeff = computeDSC(~, im1, im2)
            im1 = (im1 == true);
            im2 = (im2 == true);
            diceCoeff = 2 * sum(im1 & im2) / (sum(im1) + sum(im2));
        end
        
        function results = computePixelAccuracy(app)
            results = sum(app.humanSegmentMask(:) == app.segmentMask(:)) * 100 /numel(app.segmentMask);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            image_path = fullfile(app.datalocation, "candidate");
            file_path = dir(image_path + "*.png");
            num_images = numel(file_path);
            item_names = {};
            for idx = 1:num_images
                item_names(idx) = cellstr(sprintf("Problem%02d", idx));
            end
            app.ProblemDropDown.Items = item_names;

            % When the App opens, load the current case.
            problem_num = strrep(app.currentCase, "Problem", "");
            image_path = fullfile(app.datalocation, "candidate");
            file_path = dir(image_path + num2str(problem_num, '%02d') + "*.png");
            image_name = fullfile(app.datalocation, file_path(1).name);

            fparts = strsplit(file_path(1).name, "_");
            organ_name = fparts{2};
            app.instructionTitle = "Label the " + organ_name + " - click on 'Draw' to start!";

            label_path = fullfile(app.datalocation, "label");
            file_path = dir(label_path + num2str(problem_num, '%02d') + "*.png");
            label_name = fullfile(app.datalocation, file_path(1).name);

            app.imageToSegment = imread(image_name);
            app.imageToDisplay = app.imageToSegment;
            app.segmentMask = imread(label_name);

            app.humanSegmentMask = zeros('like', app.segmentMask);
            imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);
            xlabel(app.UIAxes, "");
            ylabel(app.UIAxes, "");
            title(app.UIAxes, app.instructionTitle);

            app.StatusLamp.Color = [0.6350 0.0780 0.1840];
            app.DiceGauge.Value = 0;
            app.HausdorffDistanceDisplay.Value = "Inf";
            app.PixelAccuracyDisplay.Value = string(app.computePixelAccuracy());
            app.ResultsTextArea.Value = "";

            % Enable the draw boundary button
            app.DrawButton.Enable = 'on';
            app.DrawLamp.Color = "Green";

            % Enable the leaderboard at all times
            app.LeaderboardButton.Enable = 'on';
            app.SaveButton.Enable = 'off';
        end

        % Value changed function: ProblemDropDown
        function ProblemDropDownValueChanged(app, event)
            value = app.ProblemDropDown.Value;

            % Ask to save segmentation to show up on leaderboard
            if any(app.humanSegmentMask(:) > 0)
                app.askToSaveSegmentation();
            end

            if string(value) ~= string(app.currentCase)
                fig = app.SegmentationEvaluatorUIFigure;
                msg = 'Changing problem will overwrite previous segmentations.';
                title = 'Confirm problem change';
                selection = uiconfirm(fig, msg, title, ...
                            'Options',{'OK', 'Cancel'}, ...
                            'DefaultOption', 1, 'CancelOption', 2);
                
                if strcmp(selection, "OK")
                    app.currentCase = value;
                    startupFcn(app);
                    app.imageToDisplay = im2uint8(rescale(app.imageToSegment));
                    imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);
                else
                    app.ProblemDropDown.Value = app.currentCase;
                end
            end
            
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.instructionTitle = "Click and drag to draw a region on the image. Press 'Confirm' when you're satisfied!";
            title(app.UIAxes, app.instructionTitle);
            app.EraseButton.Enable = 'off';
            app.DrawButton.Enable = 'off';
            
            app.newFH = drawfreehand("Parent", app.UIAxes);
            app.ConfirmButton.Enable = 'on';
            app.ConfirmLamp.Color = "Green";

            app.mode = "draw";
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if isvalid(app.newFH)
                newMask = createMask(app.newFH);
                if strcmp(app.mode, "draw")
                    app.humanSegmentMask = app.humanSegmentMask | newMask;
                elseif strcmp(app.mode, "erase")
                    app.humanSegmentMask = app.humanSegmentMask & ~newMask;
                end

                diceCoeff = app.computeDSC(app.humanSegmentMask, app.segmentMask);
                app.updateGauge(diceCoeff * 100);

                hausdorffDist = imhausdorff(uint16(app.humanSegmentMask), app.segmentMask, 'euclidean');
                app.HausdorffDistanceDisplay.Value = string(hausdorffDist);

                app.PixelAccuracyDisplay.Value = string(app.computePixelAccuracy());

                app.imageToDisplay = labeloverlay(im2uint8(rescale(app.imageToSegment)), uint8(app.humanSegmentMask));
                imshow(app.imageToDisplay, [], 'Parent', app.UIAxes);

                if app.DiceGauge.Value >= 85
                    app.StatusLamp.Color = [0.4660 0.6740 0.1880];
                    status = sprintf("Great job, your score is %f, what next?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                elseif app.DiceGauge.Value < 85 && app.DiceGauge.Value >= 70
                    app.StatusLamp.Color = [0.9290 0.6940 0.1250];
                    status = sprintf("Good progress, your score is %f, can you do better?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                else
                    app.StatusLamp.Color = [0.6350 0.0780 0.1840];
                    status = sprintf("There's some way to go: your score is %f, try again?", diceCoeff*100);
                    app.ResultsTextArea.Value = status;
                end

                delete(app.newFH)
                app.newFH = [];
                app.SaveButton.Enable = 'on';
                app.EraseButton.Enable = 'on';
                app.ConfirmButton.Enable = 'off';
                app.DrawButton.Enable = 'on';
                app.ConfirmLamp.Color = "Red";
                app.instructionTitle = "Click 'Draw' or 'Erase' to make more changes!";
                title(app.UIAxes, app.instructionTitle);
            else
                app.ConfirmButton.Enable = 'off';
                app.ConfirmLamp.Color = "Red";

                % Nothing new to erase, so do not enable it.
                app.DrawButton.Enable = 'on';
            end
            app.mode = "none";
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            app.instructionTitle = "Click and drag to erase a region on the image. Press 'Confirm' when you're satisfied!";
            title(app.UIAxes, app.instructionTitle);
            app.DrawButton.Enable = 'off';
            
            app.newFH = drawfreehand("Parent", app.UIAxes, "Color", [0.8500 0.3250 0.0980]);
            app.ConfirmButton.Enable = 'on';
            app.ConfirmLamp.Color = "Green";

            app.mode = "erase";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            fig = app.SegmentationEvaluatorUIFigure;
            msg = 'Resetting will delete all existing segmentations.';
            title = 'Confirm reset';
            selection = uiconfirm(fig, msg, title, ...
                        'Options',{'OK', 'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
            if strcmp(selection, "OK")
                app.ProblemDropDown.Value = "Problem01";
                app.currentCase = "Problem01";
                startupFcn(app);
                app.EraseButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.ResultsTextArea.Value = "";
                app.StatusLamp.Color = [0.6350 0.0780 0.1840];
            end
        end

        % Button pushed function: LeaderboardButton
        function LeaderboardButtonPushed(app, event)
            if ~exist(app.savelocation, "dir")
                message = {'No saved segmentations!','Please save some segmentations before looking here!'};
                uialert(app.SegmentationEvaluatorUIFigure, message, 'Warning', 'Icon', 'warning');
            else
                leaderboardTable = uifigure;
                t = app.constructLeaderboard();
                uitable(leaderboardTable, 'Data', t, ...
                        'position', [5, 5, leaderboardTable.Position(3) - 5, leaderboardTable.Position(4) - 45]);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            askToSaveSegmentation(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SegmentationEvaluatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationEvaluatorUIFigure = uifigure('Visible', 'off');
            app.SegmentationEvaluatorUIFigure.Name = 'Segmentation Evaluator';
            app.SegmentationEvaluatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SegmentationEvaluatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DrawButton = uibutton(app.LeftPanel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Enable = 'off';
            app.DrawButton.Text = 'Draw';
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Enable = 'off';
            app.ConfirmButton.Text = 'Confirm ';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ChooseCaseLabel = uilabel(app.LeftPanel);
            app.ChooseCaseLabel.Text = 'Problem:';
            app.ProblemDropDown = uidropdown(app.LeftPanel);
            app.ProblemDropDown.Items = {'Problem01', 'Problem02', 'Problem03', 'Problem04', 'Problem05'};
            app.ProblemDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemDropDownValueChanged, true);
            app.ProblemDropDown.Placeholder = 'empty';
            app.ProblemDropDown.Value = 'Problem01';
            app.DrawLampLabel = uilabel(app.LeftPanel);
            app.DrawLampLabel.Text = 'Draw';
            app.DrawLamp = uilamp(app.LeftPanel);
            app.ConfirmLampLabel = uilabel(app.LeftPanel);
            app.ConfirmLampLabel.Text = 'Confirm';
            app.ConfirmLamp = uilamp(app.LeftPanel);
            app.EraseButton = uibutton(app.LeftPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Enable = 'off';
            app.EraseButton.Text = 'Erase';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.DiceGauge = uigauge(app.RightPanel, 'circular');
            app.StatusLampLabel = uilabel(app.RightPanel);
            app.StatusLampLabel.Text = 'Status: ';
            app.StatusLamp = uilamp(app.RightPanel);
            app.ResultsTextAreaLabel = uilabel(app.RightPanel);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.RightPanel);
            app.DiceScoreLabel = uilabel(app.RightPanel);
            app.DiceScoreLabel.Text = 'Dice Score';
            app.LeaderboardButton = uibutton(app.RightPanel, 'push');
            app.LeaderboardButton.ButtonPushedFcn = createCallbackFcn(app, @LeaderboardButtonPushed, true);
            app.LeaderboardButton.Text = 'Leaderboard';
            app.HausdorffDistanceTextAreaLabel = uilabel(app.RightPanel);
            app.HausdorffDistanceTextAreaLabel.Text = 'Hausdorff Distance:';
            app.HausdorffDistanceDisplay = uitextarea(app.RightPanel);
            app.PixelAccuracyLabel = uilabel(app.RightPanel);
            app.PixelAccuracyLabel.Text = 'Pixel Accuracy:       ';
            app.PixelAccuracyDisplay = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.SegmentationEvaluatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationEvaluator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SegmentationEvaluatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SegmentationEvaluatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationEvaluatorUIFigure)
        end
    end
end